classdef image_process_compress < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Switch                matlab.ui.control.ToggleSwitch
        Panel_2               matlab.ui.container.Panel
        BlueButton            matlab.ui.control.Button
        GreenButton           matlab.ui.control.Button
        RedButton             matlab.ui.control.Button
        EditField_10          matlab.ui.control.EditField
        Slider_2              matlab.ui.control.Slider
        Slider                matlab.ui.control.Slider
        EditField_8           matlab.ui.control.EditField
        EditField_7           matlab.ui.control.EditField
        Panel                 matlab.ui.container.Panel
        Knob                  matlab.ui.control.Knob
        EditField_9           matlab.ui.control.EditField
        EditField_6           matlab.ui.control.EditField
        EditField_5           matlab.ui.control.EditField
        EditField_4           matlab.ui.control.EditField
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
        ColorInversionButton  matlab.ui.control.Button
        MirrorImageButton     matlab.ui.control.Button
        BrowseButton          matlab.ui.control.Button
        GrayscaleButton       matlab.ui.control.Button
        EditField_3           matlab.ui.control.EditField
        EditField_2           matlab.ui.control.EditField
        EditField             matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global image1;
            [filename pathname]= uigetfile({'*.jpg'},'Open file')
            fullpathname = strcat(pathname, filename);
            image1 = imread(fullpathname);
            imshow(image1,'parent',app.UIAxes);
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
            global image1;
            global y;
            y=rgb2gray(image1);
            imshow(y,'parent',app.UIAxes_2);
        end

        % Button pushed function: MirrorImageButton
        function MirrorImageButtonPushed(app, event)
            global image1;
            % Flip the original image horizontally to create a mirror image
            mirrorImage = flip(image1, 2);
            %mirrorImage = image1(:, end:-1:1)
            
            % Display the mirror image in the UIAxes
            imshow(mirrorImage, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: ColorInversionButton
        function ColorInversionButtonPushed(app, event)
            global image1;
            global y;
            %x = imread(y);
            %[m,n]=size(x);
            %z=255*ones(m,n);
            %k = z-x;
            complement = imcomplement(image1);
            imshow(complement,'Parent',app.UIAxes_2);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            global y;
            global D;
            global compressedSizeKB;
            I2 = im2double(y);
            [u, s, v] = svd(I2);
            % Apply compression ratio to singular values
            s2 = s;
            s2(value:end, :) = 0;
            s2(:, value:end) = 0;
            D = u * s2 * v';
            
            % Update the compressed image in UIAxes3
            imshow(D, 'Parent', app.UIAxes_3);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            global image1;
           
            % Perform SVD on each color channel separately
            red_channel = double(image1(:,:,1));
            green_channel = double(image1(:,:,2));
            blue_channel = double(image1(:,:,3));
            
            [U_red, S_red, V_red] = svd(red_channel);
            [U_green, S_green, V_green] = svd(green_channel);
            [U_blue, S_blue, V_blue] = svd(blue_channel);
            
            % Reconstruct the image using a subset of singular values (e.g., keeping the first k singular values)
             % Number of singular values to keep = value
            k= value;
            red_reconstructed = U_red(:,1:k) * S_red(1:k,1:k) * V_red(:,1:k)';
            green_reconstructed = U_green(:,1:k) * S_green(1:k,1:k) * V_green(:,1:k)';
            blue_reconstructed = U_blue(:,1:k) * S_blue(1:k,1:k) * V_blue(:,1:k)';
            
            % Concatenate the reconstructed color channels
            reconstructed_image = uint8(cat(3, red_reconstructed, green_reconstructed, blue_reconstructed));
            imshow(reconstructed_image, 'Parent', app.UIAxes_2);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'EXIT')
                % Perform exit operations here
                uialert(app.UIFigure, 'Exiting the program', 'EXIT');
                delete(app.UIFigure); % Close the app window
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            global image1;
            value = app.Knob.Value;
            rotatedImage = imrotate(image1, value);
            imshow(rotatedImage, 'Parent', app.UIAxes_2);
            
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            global image1; 
            redTintImage = image1;
            redTintImage(:,:,1) = redTintImage(:,:,1) + 100; % Increase the red channel values
            imshow(redTintImage, 'Parent', app.UIAxes_2);
        end

        % Callback function
        function GreenButtonPushed(app, event)
            
        end

        % Callback function
        function BlueButtonPushed(app, event)
            
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed2(app, event)
            global image1; 
            greenTintImage = image1;
            greenTintImage(:,:,2) = greenTintImage(:,:,2) + 100; % Increase the red channel values
            imshow(greenTintImage, 'Parent', app.UIAxes_2);
        end

        % Button pushed function: BlueButton
        function BlueButtonPushed2(app, event)
            global image1; 
            blueTintImage = image1;
            blueTintImage(:,:,3) = blueTintImage(:,:,3) + 100; % Increase the red channel values
            imshow(blueTintImage, 'Parent', app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '                                                  Image Processing and Compression';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = '         Operations';
            app.GrayscaleButton = uibutton(app.UIFigure, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.MirrorImageButton = uibutton(app.UIFigure, 'push');
            app.MirrorImageButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorImageButtonPushed, true);
            app.MirrorImageButton.Text = 'Mirror Image';
            app.ColorInversionButton = uibutton(app.UIFigure, 'push');
            app.ColorInversionButton.ButtonPushedFcn = createCallbackFcn(app, @ColorInversionButtonPushed, true);
            app.ColorInversionButton.Text = 'Color Inversion';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.Panel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.Panel);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.EditField_4 = uieditfield(app.Panel, 'text');
            app.EditField_4.Value = '     Original Image';
            app.EditField_5 = uieditfield(app.Panel, 'text');
            app.EditField_5.Value = '     Operated Image';
            app.EditField_6 = uieditfield(app.Panel, 'text');
            app.EditField_6.Value = '  Compressed Image';
            app.EditField_9 = uieditfield(app.Panel, 'text');
            app.EditField_9.Value = '     Rotation';
            app.Knob = uiknob(app.Panel, 'continuous');
            app.Knob.Limits = [0 360];
            app.Knob.MajorTicks = [0 50 100 150 200 250 300 350];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.EditField_7 = uieditfield(app.Panel_2, 'text');
            app.EditField_7.Value = '                    Compression Slider';
            app.EditField_8 = uieditfield(app.Panel_2, 'text');
            app.EditField_8.Value = '                    Colorful Compression ';
            app.Slider = uislider(app.Panel_2);
            app.Slider.Limits = [1 50];
            app.Slider.MajorTicks = [1 10 20 30 40 50];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.Slider_2 = uislider(app.Panel_2);
            app.Slider_2.Limits = [1 50];
            app.Slider_2.MajorTicks = [1 10 20 30 40 50];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 1;
            app.EditField_10 = uieditfield(app.Panel_2, 'text');
            app.EditField_10.Value = '                               Colors';
            app.RedButton = uibutton(app.Panel_2, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            app.GreenButton = uibutton(app.Panel_2, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed2, true);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uibutton(app.Panel_2, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @BlueButtonPushed2, true);
            app.BlueButton.Text = 'Blue';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'', 'EXIT'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_process_compress

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end